classdef SLOSH_v2_ARC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SLOSH_2UIFigure          matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        SavesessionMenu          matlab.ui.container.Menu
        LoadsessionMenu          matlab.ui.container.Menu
        Switch                   matlab.ui.control.Switch
        CALCULATE                matlab.ui.control.Button
        TabGroup2                matlab.ui.container.TabGroup
        BulkOutputTab            matlab.ui.container.Tab
        msLabel                  matlab.ui.control.Label
        VcEditField              matlab.ui.control.NumericEditField
        VcEditFieldLabel         matlab.ui.control.Label
        FrEditField              matlab.ui.control.NumericEditField
        FrEditFieldLabel         matlab.ui.control.Label
        BoEditField              matlab.ui.control.NumericEditField
        BoEditFieldLabel         matlab.ui.control.Label
        WeEditField              matlab.ui.control.NumericEditField
        WeEditFieldLabel         matlab.ui.control.Label
        RegimeLabel              matlab.ui.control.Label
        NaturalFrequenciesLabel  matlab.ui.control.Label
        EigenvectorsLabel        matlab.ui.control.Label
        freqtable                matlab.ui.control.Table
        eigfntable               matlab.ui.control.Table
        ModesTab                 matlab.ui.container.Tab
        Label_3                  matlab.ui.control.Label
        modesMF                  matlab.ui.control.Spinner
        MultFactorLabel          matlab.ui.control.Label
        HzLabel                  matlab.ui.control.Label
        modes_nondfreq           matlab.ui.control.Label
        modes_natfreq            matlab.ui.control.Label
        NondimFrequencyLabel     matlab.ui.control.Label
        NaturalFrequencyLabel    matlab.ui.control.Label
        ModeSpinner              matlab.ui.control.Spinner
        ModeLabel                matlab.ui.control.Label
        ModalCoefficientsLabel   matlab.ui.control.Label
        freqtable_2              matlab.ui.control.Table
        PendulumTab              matlab.ui.container.Tab
        PendulumTable            matlab.ui.control.Table
        SpringTab                matlab.ui.container.Tab
        SpringTable              matlab.ui.control.Table
        ButtonGroup              matlab.ui.container.ButtonGroup
        ModesButton              matlab.ui.control.ToggleButton
        MechanicalButton         matlab.ui.control.ToggleButton
        GeometryButton           matlab.ui.control.ToggleButton
        TabGroup                 matlab.ui.container.TabGroup
        GeometryTab              matlab.ui.container.Tab
        Label_5                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        ZF                       matlab.ui.control.Spinner
        ZFactorLabel             matlab.ui.control.Label
        RF                       matlab.ui.control.Spinner
        RFactorLabel             matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        LiquidFillRatio          matlab.ui.control.Spinner
        FillRatioLabel           matlab.ui.control.Label
        OuterContourButton       matlab.ui.control.Button
        InnerContourButton       matlab.ui.control.Button
        PhysicsTab               matlab.ui.container.Tab
        rhoEditField             matlab.ui.control.NumericEditField
        Label_2                  matlab.ui.control.Label
        Kgm3Label                matlab.ui.control.Label
        sigmaEditField           matlab.ui.control.NumericEditField
        EditFieldLabel           matlab.ui.control.Label
        mNmLabel                 matlab.ui.control.Label
        ms2Label                 matlab.ui.control.Label
        gEditField               matlab.ui.control.NumericEditField
        gEditFieldLabel          matlab.ui.control.Label
        SimulationTab            matlab.ui.container.Tab
        SpringModeSpinner        matlab.ui.control.Spinner
        ShowModesLabel           matlab.ui.control.Label
        nSpinner                 matlab.ui.control.Spinner
        nSpinnerLabel            matlab.ui.control.Label
        MSpinner                 matlab.ui.control.Spinner
        MSpinnerLabel            matlab.ui.control.Label
        NSpinner                 matlab.ui.control.Spinner
        NSpinnerLabel            matlab.ui.control.Label
        ParamAnTab               matlab.ui.container.Tab
        ExportButton             matlab.ui.control.Button
        Ngrav                    matlab.ui.control.Spinner
        NSpinner_3Label          matlab.ui.control.Label
        NFR                      matlab.ui.control.Spinner
        NSpinner_2Label          matlab.ui.control.Label
        Label_8                  matlab.ui.control.Label
        ms2Label_3               matlab.ui.control.Label
        ms2Label_2               matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        g_max                    matlab.ui.control.Spinner
        g_maxSpinnerLabel        matlab.ui.control.Label
        g_min                    matlab.ui.control.Spinner
        g_minSpinnerLabel        matlab.ui.control.Label
        FR_max                   matlab.ui.control.Spinner
        FR_maxSpinnerLabel       matlab.ui.control.Label
        FR_min                   matlab.ui.control.Spinner
        FR_minSpinnerLabel       matlab.ui.control.Label
        mainaxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        IC  % Internal contour function handle
        ICa % Dimensionless internal contour function handle
        bIC % Boundaries of IC data ([rmin,rmax,zmin,zmax])
        OC  % Outer contour function handle
        OCa % Dimensionless outer contour function handle
        bOC % Boundaries of OC data ([rmin,rmax,zmin,zmax])
        ep  % Dimensionless inner surface contour (epsilon)
        Lf  % Dimensionless fluid surface height wrt CoM
        af  % Fluid surface external radius
        bens% Dimensionless region boundaries
        Nm  % N employed during last calculation
        Mm  % M employed during last calculation
        gm  % g employed during last calculation
        Vm  % Eigenvectors
        Dm  % Eigenvalues
        Km  % Dimensionless natural frequencies
        wm  % Natura frequencies
        hkm  %intermediate parameter for the calculations
        Ph_am %intermediate parameter for the calculations
        Bm   %intermediate parameter for the calculations
        Bzerosm % dBessel zeros
        flvolf % Fluid Volume
        Hf  % Fluid Height
        Hf1 %Fluid height from the bottom of the tank
        CM_pos % Fluid Center of Mass position (z)
        Hmods % Modal mass heights
        Kmods % Modal sring stiffnesses
        HmodsSpring % Spring mass hinge point
        FR_LowValue %
        FR_HighValue
        FR_Interval
    end

    methods (Access = private)

        function updategeomgraph(app,event)
            % event =
            %   0 if only the geometry should be updated
            %   1 if the modes should be plotted over the geometry
            %   2 if the spring masses should be plotted

            % Load data for event = 0
            r_ic  = app.IC;
            r_oc  = app.OC;
            b_ic  = app.bIC;
            b_oc  = app.bOC;
            LFR   = app.LiquidFillRatio.Value/100;
            dircal= app.Switch.Value;
            RFac  = app.RF.Value/100;
            ZFac  = app.ZF.Value/100;

            % Load data for event = 1
            Bzeros= app.Bzerosm;
            w     = app.wm;
            D     = app.Dm;
            V     = app.Vm;
            msel  = app.ModeSpinner.Value;
            N     = app.Nm;
            M     = app.Mm;
            g     = app.gm;
            mMF   = app.modesMF.Value/100;
            rho   = app.rhoEditField.Value;

            % Load data for event = 2
            ssel = app.SpringModeSpinner.Value;


            % Check consistency - stage 1
            c1 = isa(r_ic, 'function_handle');
            c2 = isa(r_oc, 'function_handle');

            if c1 && c2
                % Check consistency - stage 2
                c3 = max(b_ic(3),b_oc(3)) < min(b_ic(4),b_oc(4));

                if c3
                    % Define new boundaries ([rmin,rmax,zmin,zmax])
                    b_ens = [min(b_ic(1),b_oc(1))*RFac, max(b_ic(2),b_oc(2))*RFac, max(b_ic(3),b_oc(3))*ZFac, min(b_ic(4),b_oc(4))*ZFac];
                    H     = LFR*(b_ens(4)-b_ens(3)) + b_ens(3); % Fluid height (wrt bottom)
                    H1    = LFR*(b_ens(4)-b_ens(3));

                    % Calculate fluid volume
                    flvol = integral(@(z) pi*((r_oc(z).^2)-(r_ic(z).^2)),b_ens(3),H);

                    %Testing container boundaries without z_CM offset
                    r_ic2  = @(z) r_ic(z/ZFac)*RFac;
                    r_oc2 = @(z) r_oc(z/ZFac)*RFac;

                    % Scale geometry
                    r_ic  = @(z) r_ic(z/ZFac)*RFac;
                    r_oc  = @(z) r_oc(z/ZFac)*RFac;

                    % Calculate Center of mass
                    z_CM = integral(@(z) z.*((r_oc(z).^2) -(r_ic(z).^2)),b_ens(3),H)/integral(@(z) r_oc(z).^2 -r_ic(z).^2 ,b_ens(3),H);

                    % Center axis at CoM
                    b_ens    = b_ens - [0,0,z_CM,z_CM];
                    r_ic     = @(z) r_ic(z + z_CM);
                    r_oc     = @(z) r_oc(z + z_CM);
                    L0       = H-z_CM; % Fluid level (wrt CM)

                    % Plot geometry
                    zv    = linspace(b_ens(3),b_ens(4),1000);
                    hold(app.mainaxes,'off')
                    axis(app.mainaxes,'equal')
                    gapr  = (b_ens(2)-b_ens(1))/10;
                    gapz  = (b_ens(4)-b_ens(3))/10;
                    gap   = mean([gapr,gapz]);
                    axis(app.mainaxes,b_ens + [-b_ens(1),gap,-gap,gap])

                    % Fuel tank
                    plot(app.mainaxes,r_ic(zv),zv,'k','LineWidth',2)
                    hold(app.mainaxes,'on')
                    plot(app.mainaxes,r_oc(zv),zv + z_CM,'k','LineWidth',2)
                    plot(app.mainaxes,[r_ic(b_ens(3)*0.99999),r_oc(b_ens(3)*0.99999)],b_ens(3)*[1,1],'k','LineWidth',2)
                    plot(app.mainaxes,[r_ic(b_ens(4)*0),r_oc(b_ens(4)*0.99999)],b_ens(4)*[1,1],'k','LineWidth',2)
                    
                    %re-adjust ylim for plot
                    ylim(app.mainaxes, [0, b_oc(end) + 1]);

                    % Fluid level
                    plot(app.mainaxes,[r_ic(L0),r_oc(L0)],[L0,L0],'b','LineWidth',1)

                    % General Axes and CM
                    plot(app.mainaxes,0,0,'ko','Markersize',10)
                    plot(app.mainaxes,[0,1.5*r_oc(0)], [0,0],'g','LineWidth',1)
                    %plot(app.mainaxes,[0,1.5*r_oc(0)], [0,0],'g','LineWidth',1)

                    % Mode shape (if event = 1)
                    if event == 1
                        % Reorder index
                        [~,I]  = sort(diag(abs(D)));
                        msel   = I(msel);

                        % Calculate mode
                        zM     = zeros(1,200);
                        rM      = linspace(r_ic(L0),r_oc(L0),200);
                        for  n = 1:N
                            zM = zM + V(n,msel)*phi(n,M,rM/r_oc(L0),L0,Bzeros,L0) * w(msel)/g; %%REVISAR ESTO ( HARIA FALTA INCLUIR EL r_ic? )
                        end

                        % Plot
                        plot(app.mainaxes,rM,zM*mMF+L0,'r')
                    elseif event == 2
                        % Load K-values
                        Kmods   = app.Kmods;
                        Hmods   = app.HmodsSpring;

                        % Plot springs
                        for n = 1:ssel+1
                            Hmod = Hmods(n)+b_ens(3) + z_CM;
                            if Hmod >= b_ens(4) | Hmod <= b_ens(3)
                                continue
                            end
                            left = r_ic(Hmod);
                            right = r_oc(Hmod);
                            mid = (left + right) ./ 2;
                            xunit = abs(b_ens(2) - b_ens(1)) ./ 10;
                            yunit = abs(b_ens(4) - b_ens(3)) ./ 25;
                            [springx, springy] = horizspring(mid./4, Hmod, mid./4)
                            plot(app.mainaxes, [left,mid./4], [Hmod, Hmod], 'Color', [0.2, 0.2, 0.2]);
                            plot(app.mainaxes, springx, springy, 'Color', [0.2, 0.2, 0.2]);
                            plot(app.mainaxes, [mid./2, right], [Hmod, Hmod], 'Color', [0.2, 0.2, 0.2]);
                            rectangle(app.mainaxes, 'Position', ...
                                [mid-xunit, Hmod-yunit, xunit.*2, yunit.*2], ...
                                'FaceColor',[0.8, 0.8, 0.8])
                            text(app.mainaxes, mid, Hmod, ['m_', num2str(n-1)], ...
                                'HorizontalAlignment','center')
                        end
                    end

                    % Generate relevant geometrical parameters
                    a        = r_oc(L0);
                    e0       = r_ic(L0)/a;
                    L0       = L0/a;

                    % Save relevant geometrical parameters
                    app.ep   = e0;
                    app.Lf   = L0;
                    app.af   = a;
                    app.ICa  = @(Z) r_ic(Z*a)/a;
                    app.OCa  = @(Z) r_oc(Z*a)/a;

                    %Testing container boundaries without z_CM offset
                    %                     app.IC2  = @(Z) r_ic2(Z*a)/a;
                    %                     app.OC2  = @(Z) r_oc2(Z*a)/a;
                    %                     app.bens2 = b_ens2;

                    app.bens = b_ens;
                    app.flvolf= flvol;
                    app.Hf    = H;
                    app.Hf1   = H1;
                    app.CM_pos= z_CM;

                    % Activate CALCULATE button and locker, and run if locked
                    if strcmp(app.Switch.Enable,'off')
                        app.CALCULATE.Enable   = 1;
                        app.Switch.Enable      = 1;
                        app.ModeSpinner.Enable = 1;
                        app.SpringModeSpinner.Enable    = 1;
                    end
                    if strcmp(dircal,'Locked') && event == 0
                        calculate(app, 0);
                    end
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('./functions')
            movegui(app.SLOSH_2UIFigure,'center')
        end

        % Button pushed function: InnerContourButton
        function inner_contour(app, event)
            [file,path] = uigetfile('*.txt','Select an inner contour matrix');
            figure(app.SLOSH_2UIFigure)
            if max(path ~= 0)
                % Load file
                ZR          = dlmread([path,file]);

                % Process file
                Z           = ZR(:,1);
                R           = ZR(:,2);
                [Z,I]       = sort(Z);
                R           = R(I);

                % Interpolation function
                r_ic        = @(z) interp1(Z,R,z);

                % Plot data
                zv          = linspace(min(Z),max(Z),1000);
                hold(app.mainaxes,'off')
                plot(app.mainaxes,R,Z,'r+',r_ic(zv),zv,'k');

                % Store values
                app.IC      = r_ic;
                app.bIC     = [min(R),max(R),min(Z),max(Z)];

                % Update Graph
                updategeomgraph(app,0);
            end
        end

        % Button pushed function: OuterContourButton
        function outer_contour(app, event)
            [file,path] = uigetfile('*.txt','Select an outer contour matrix');
            figure(app.SLOSH_2UIFigure)
            if max(path ~= 0)
                % Load file
                ZR          = dlmread([path,file]);

                % Process file
                Z           = ZR(:,1);
                R           = ZR(:,2);
                %rearranges the Z and also provides a key with how the
                %indexes has been changed
                [Z,I]       = sort(Z);
                R           = R(I);

                % Interpolation function
                r_oc        = @(z) interp1(Z,R,z);

                % Plot data
                hold(app.mainaxes,'off')
                zv          = linspace(min(Z),max(Z),1000);
                plot(app.mainaxes,R,Z,'r+',r_oc(zv),zv,'k');

                % Store values
                app.OC      = r_oc;
                app.bOC     = [min(R),max(R),min(Z),max(Z)];

                % Update Graph
                updategeomgraph(app,0);
            end
        end

        % Button pushed function: CALCULATE
        function calculate(app, event)
            % Load parameters
            e      = app.ep;
            L      = app.Lf;
            a      = app.af;
            r_ic   = app.ICa;
            r_oc   = app.OCa;
            intb   = app.bens;
            nsteps = app.nSpinner.Value;
            N      = app.NSpinner.Value;
            M      = app.MSpinner.Value;
            g      = app.gEditField.Value;
            sigma  = app.sigmaEditField.Value;
            rho    = app.rhoEditField.Value;
            flvol  = app.flvolf;
            H      = app.Hf1;
            H2     = app.Hf;
            z_CM   = app.CM_pos;
            selm   = app.SpringModeSpinner.Value;

            % Calculation
            [V,D,Bzeros]  = core(N,M,nsteps,intb,e,L,r_ic,r_oc);

            % Reordering modes (TO BE FIXED / CHECKED)
            [~,I]  = sort(diag(abs(D))); %%%%%%%%%%%%%%% ABS!!
            D      = diag(D);
            D      = D(I);
            D      = diag(D);
            V      = V(:,I);

            % Derived parameters pre-calculation
            B             = zeros(N,N);
            hk            = zeros(N,1);
            Ph_a          = zeros(N,1);
            for n = 1:N
                hk(n,1)   = integral(@(z) (z-intb(3)/a).*r_oc(z).*phi(n,M,r_oc(z),z,Bzeros,L),intb(3)/a,L) - ...
                    integral(@(z) (z-intb(3)/a).*r_ic(z).*phi(n,M,r_ic(z),z,Bzeros,L),intb(3)/a,L);
                %
                %                   Ph_a(n,1) = sum(V(:,n).*phi(n,M,r_oc(L),L,Bzeros,L));
                Ph_a(n,1) = sum(V(n,:).*phi(n,M,r_oc(L),L,Bzeros,L));
                %                   Ph_a(n,1) = phi(n,M,r_oc(L),L,Bzeros,L);
                %                 Ph_a(n,1) = sum(V(:,n).*integral(@(r) phi(n,M,r,L,Bzeros,L),r_ic(L),r_oc(L)));
                %                 Ph_a(n,1) = integral(@(r) phi(n,M,r,L,Bzeros,L),r_ic(L),r_oc(L));

                for m = n:N
                    B(n,m) = Bint(n,m,M,Bzeros,e);
                end
            end

            % Symmetry of B
            for m = 1:N
                for n = 1:N
                    B(n,m) = B(m,n);
                end
            end

            % Derived pendulum parameters
            [K,w,Vc,Mmods,Lmods,Hmods,eta0n_xn] = derivedparams(rho,flvol,H,V,D,g,L*a,a,B,hk,Ph_a,intb,selm);

            % Derived spring parameters
            [Kmods,HmodsSpring] = pendulum2spring(Mmods, Lmods, g, Hmods);

            % Save
            app.Vm      = V;
            app.Dm      = D;
            app.Km      = K;
            app.wm      = w;
            app.Bzerosm = Bzeros;
            app.Nm      = N;
            app.Mm      = M;
            app.gm      = g;
            app.hkm     = hk;
            app.Ph_am   = Ph_a;
            app.Bm      = B;
            app.Hmods   = Hmods;
            app.Kmods   = Kmods;
            app.HmodsSpring = HmodsSpring;

            % Dimensionless numbers and regimes
            We     = rho*Vc^2*a/sigma;
            Fr     = Vc^2/g/a;
            Bo     = We/Fr;

            if We >= 1 && We >= Bo
                regime = 'Inertia D.';
                color  = [0,1,0];
            elseif Bo >= 1 && Bo > We
                regime = 'Gravity D.';
                color  = [0,1,0];
            else
                regime = 'Capillary D.';
                color  = [1,0,0];
            end

            %            SPHERE CHECK (p.17 Dodge)
            %            wa = w*sqrt(10/g);
            %            war = sort(real(wa));

            % Display Regime information
            app.VcEditField.Value      = Vc;
            app.WeEditField.Value      = We;
            app.FrEditField.Value      = Fr;
            app.BoEditField.Value      = Bo;
            app.RegimeLabel.Text       = regime;
            app.RegimeLabel.FontColor  = color;

            % Display Frequencies
            data                       = [diag(D)';K';w'];
            app.freqtable.Data         = data;
            app.freqtable.ColumnName   = 'numbered';
            app.freqtable.ColumnWidth  = repmat({60},1,length(K));
            app.freqtable.ColumnFormat = repmat({'short'},1,length(K));
            app.freqtable.RowName      = {'lambda';'K';'wn'};

            % Display Eigenvectors
            app.eigfntable.Data        = V;
            app.eigfntable.RowName     = 'numbered';
            app.eigfntable.ColumnName  = 'numbered';
            app.eigfntable.ColumnWidth = repmat({60},1,length(K));
            app.eigfntable.ColumnFormat= repmat({'short'},1,length(K));

            % Display pendulum properties
            app.PendulumTable.Data        = [Mmods(1:selm+1),Lmods(1:selm+1),Hmods(1:selm+1),eta0n_xn(1:selm+1)];
            app.PendulumTable.RowName     = 'numbered';
            app.PendulumTable.ColumnWidth = repmat({80},1,length(K));
            app.PendulumTable.ColumnFormat= repmat({'short'},1,length(K));

            % Display spring properties
            app.SpringTable.Data        = [Mmods(1:selm+1),HmodsSpring(1:selm+1),Kmods(1:selm+1)];
            app.SpringTable.RowName     = 'numbered';
            app.SpringTable.ColumnWidth = repmat({80},1,length(K));
            app.SpringTable.ColumnFormat= repmat({'short'},1,length(K));

            updategeomgraph(app,2);

        end

        % Selection changed function: ButtonGroup
        function dispchange(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;

        end

        % Value changed function: ModeSpinner
        function modeplot(app, event)
            % Load parameters
            V     = app.Vm;
            D     = app.Dm;
            K     = app.Km;
            w     = app.wm;
            selm  = app.ModeSpinner.Value;

            if ~isempty(V)
                % Reorder
                [~,I]  = sort(diag(abs(D)));
                D      = diag(D);
                D      = D(I);
                D      = diag(D);
                w      = w(I);
                K      = K(I);
                V      = V(:,I);

                % Update interface
                app.modes_natfreq.Text  = num2str(w(selm));
                app.modes_nondfreq.Text = num2str(K(selm));
                app.freqtable_2.Data = V(:,selm)';
                app.freqtable_2.ColumnWidth = repmat({60},1,length(K));
                app.freqtable_2.ColumnName  = 'numbered';
                app.freqtable_2.ColumnFormat= repmat({'short'},1,length(K));

                % Plot
                updategeomgraph(app,1);
            end
        end

        % Value changed function: LiquidFillRatio
        function LiquidFillRationChanged(app, event)
            %value = app.LiquidFillRatio.Value;
            updategeomgraph(app,0);
        end

        % Value changed function: Switch
        function holdcalculate(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Locked')
                app.CALCULATE.Enable = 0;
            else
                app.CALCULATE.Enable = 1;
            end
        end

        % Value changed function: gEditField
        function changegravity(app, event)
            % Load parameters
            g      = app.gEditField.Value;
            V      = app.Vm;
            D      = app.Dm;
            L      = app.Lf;
            a      = app.af;
            sigma  = app.sigmaEditField.Value;
            rho    = app.rhoEditField.Value;

            r_ic   = app.ICa;
            r_oc   = app.OCa;
            intb   = app.bens;
            M      = app.MSpinner.Value;
            flvol  = app.flvolf;
            H      = app.Hf1;
            z_CM   = app.CM_pos;
            Bzeros = ones(10,1);
            selm  = app.ModeSpinner.Value;

            N=10;
            B = ones(N,N);
            Ph_a = 1;
            hk = ones(N,1);

            if ~isempty(V)
                % Derived parameters
                %                 [K,w,Vc]  = derivedparams(V,D,g,L,a);
                [K,w,Vc] = derivedparams(rho,flvol,H,V,D,g,L*a,a,B,hk,Ph_a,intb,selm);


                % Dimensionless numbers and regimes
                We     = rho*Vc^2*a/sigma;
                Fr     = Vc^2/g/a;
                Bo     = We/Fr;

                if We >= 1 && We >= Bo
                    regime = 'Inertia D.';
                    color  = [0,1,0];
                elseif Bo >= 1 && Bo > We
                    regime = 'Gravity D.';
                    color  = [0,1,0];
                else
                    regime = 'Capillary D.';
                    color  = [1,0,0];
                end

                % Save
                app.Km = K;
                app.wm = w;
                app.gm = g;


                % Display Regime information
                app.VcEditField.Value      = Vc;
                app.WeEditField.Value      = We;
                app.FrEditField.Value      = Fr;
                app.BoEditField.Value      = Bo;
                app.RegimeLabel.Text       = regime;
                app.RegimeLabel.FontColor  = color;

                % Update frequency table
                app.freqtable.Data = [diag(D)';K';w'];
            end
        end

        % Value changed function: sigmaEditField
        function changetension(app, event)
            % Load parameters
            g      = app.gEditField.Value;
            V      = app.Vm;
            D      = app.Dm;
            L      = app.Lf;
            a      = app.af;
            sigma  = app.sigmaEditField.Value;
            rho    = app.rhoEditField.Value;

            r_ic   = app.ICa;
            r_oc   = app.OCa;
            intb   = app.bens;
            M      = app.MSpinner.Value;
            g      = app.gEditField.Value;
            flvol  = app.flvolf;
            H      = app.Hf1;
            z_CM   = app.CM_pos;
            Bzeros = ones(10,1);
            selm  = app.ModeSpinner.Value;

            N=10;
            B = ones(N,N);
            Ph_a = 1;
            hk = ones(N,1);

            if ~isempty(V)
                % Derived parameters
                %                 [~,~,Vc]  = derivedparams(V,D,g,L,a);
                [~,~,Vc]  = derivedparams(rho,flvol,H,V,D,g,L*a,a,B,hk,Ph_a,intb,selm);


                % Dimensionless numbers and regimes
                We     = rho*Vc^2*a/sigma;
                Fr     = Vc^2/g/a;
                Bo     = We/Fr;

                if We >= 1 && We >= Bo
                    regime = 'Inertia D.';
                    color  = [0,1,0];
                elseif Bo >= 1 && Bo > We
                    regime = 'Gravity D.';
                    color  = [0,1,0];
                else
                    regime = 'Capillary D.';
                    color  = [1,0,0];
                end

                % Display Regime information
                app.VcEditField.Value      = Vc;
                app.WeEditField.Value      = We;
                app.FrEditField.Value      = Fr;
                app.BoEditField.Value      = Bo;
                app.RegimeLabel.Text       = regime;
                app.RegimeLabel.FontColor  = color;
            end
        end

        % Value changed function: rhoEditField
        function changedensity(app, event)
            % Load parameters
            g      = app.gEditField.Value;
            V      = app.Vm;
            D      = app.Dm;
            L      = app.Lf;
            a      = app.af;
            sigma  = app.sigmaEditField.Value;
            rho    = app.rhoEditField.Value;

            r_ic   = app.ICa;
            r_oc   = app.OCa;
            intb   = app.bens;
            M      = app.MSpinner.Value;
            g      = app.gEditField.Value;
            flvol  = app.flvolf;
            H      = app.Hf1;
            z_CM   = app.CM_pos;
            Bzeros = ones(10,1);

            N=10;
            B = ones(N,N);
            Ph_a = 1;
            hk = ones(N,1);
            selm  = app.ModeSpinner.Value;


            if ~isempty(V)
                % Derived parameters
                [~,~,Vc]  = derivedparams(rho,flvol,H,V,D,g,L*a,a,B,hk,Ph_a,intb,selm);
                %                 [~,~,Vc]  = derivedparams(V,D,g,L,a);


                % Dimensionless numbers and regimes
                We     = rho*Vc^2*a/sigma;
                Fr     = Vc^2/g/a;
                Bo     = We/Fr;

                if We >= 1 && We >= Bo
                    regime = 'Inertia D.';
                    color  = [0,1,0];
                elseif Bo >= 1 && Bo > We
                    regime = 'Gravity D.';
                    color  = [0,1,0];
                else
                    regime = 'Capillary D.';
                    color  = [1,0,0];
                end

                % Display Regime information
                app.VcEditField.Value      = Vc;
                app.WeEditField.Value      = We;
                app.FrEditField.Value      = Fr;
                app.BoEditField.Value      = Bo;
                app.RegimeLabel.Text       = regime;
                app.RegimeLabel.FontColor  = color;
            end
        end

        % Value changed function: NSpinner
        function changeN(app, event)
            N = app.NSpinner.Value;
            app.MSpinner.Limits = [5,N-1];
            app.ModeSpinner.Limits = [1,N];
        end

        % Value changed function: modesMF
        function changemodeMF(app, event)
            updategeomgraph(app,1)
        end

        % Value changed function: RF
        function changeRF(app, event)
            updategeomgraph(app,0);
        end

        % Value changed function: ZF
        function changeZF(app, event)
            updategeomgraph(app,0);
        end

        % Value changed function: FR_max
        function FR_maxValueChanged(app, event)
            value = app.FR_max.Value;

        end

        % Value changed function: FR_min
        function FR_minValueChanged(app, event)
            value = app.FR_min.Value;

        end

        % Value changed function: SpringModeSpinner
        function changeSpringModeMF(app, event)
            % Load parameters
            g      = app.gEditField.Value;
            V      = app.Vm;
            D      = app.Dm;
            L      = app.Lf;
            a      = app.af;
            sigma  = app.sigmaEditField.Value;
            rho    = app.rhoEditField.Value;

            r_ic   = app.ICa;
            r_oc   = app.OCa;
            intb   = app.bens;
            N      = app.NSpinner.Value;
            M      = app.MSpinner.Value;
            flvol  = app.flvolf;
            H      = app.Hf1;
            z_CM   = app.CM_pos;
            Bzeros = ones(10,1);
            selm  = app.ModeSpinner.Value;


            calculate(app,0)


        end

        % Button down function: GeometryTab
        function GeometryTabButtonDown(app, event)

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Sweep parameters
            FRmin  = app.FR_min.Value; % Minimum filling ratio
            NFR    = app.NFR.Value;    % Number of steps in filling ratio
            FRmax  = app.FR_max.Value; % Maximum filling ratio
            gmin   = app.g_min.Value;  % Minimum acceleration
            Ng     = app.Ngrav.Value;  % Number of steps in aceleration
            gmax   = app.g_max.Value;  % Maximum acceleration

            % Sweep
            FRvec  = linspace(FRmin,FRmax,NFR);
            gvec   = linspace(gmin,gmax,Ng);

            for i = 1:length(FRvec)
                for j = 1:length(gvec)
                    
                    % Update graphical parameters based on sweep
                    app.LiquidFillRatio.Value = FRvec(i);
                    app.gEditField.Value       = gvec(j);
                    updategeomgraph(app,event)

                    % Load parameters
                    e      = app.ep;
                    L      = app.Lf;
                    a      = app.af;
                    r_ic   = app.ICa;
                    r_oc   = app.OCa;
                    intb   = app.bens;
                    nsteps = app.nSpinner.Value;
                    N      = app.NSpinner.Value;
                    M      = app.MSpinner.Value;
                    g      = app.gEditField.Value;
                    sigma  = app.sigmaEditField.Value;
                    rho    = app.rhoEditField.Value;
                    flvol  = app.flvolf;
                    H      = app.Hf1;
                    H2     = app.Hf;
                    z_CM   = app.CM_pos;
                    selm   = app.SpringModeSpinner.Value;

                    % Calculation
                    [V,D,Bzeros]  = core(N,M,nsteps,intb,e,L,r_ic,r_oc);

                    % Reordering modes (TO BE FIXED / CHECKED)
                    [~,I]  = sort(diag(abs(D))); %%%%%%%%%%%%%%% ABS!!
                    D      = diag(D);
                    D      = D(I);
                    D      = diag(D);
                    V      = V(:,I);

                    % Derived parameters pre-calculation
                    B             = zeros(N,N);
                    hk            = zeros(N,1);
                    Ph_a          = zeros(N,1);
                    for n = 1:N
                        hk(n,1)   = integral(@(z) (z-intb(3)/a).*r_oc(z).*phi(n,M,r_oc(z),z,Bzeros,L),intb(3)/a,L) - ...
                            integral(@(z) (z-intb(3)/a).*r_ic(z).*phi(n,M,r_ic(z),z,Bzeros,L),intb(3)/a,L);
                        %
                        %                   Ph_a(n,1) = sum(V(:,n).*phi(n,M,r_oc(L),L,Bzeros,L));
                        Ph_a(n,1) = sum(V(n,:).*phi(n,M,r_oc(L),L,Bzeros,L));
                        %                   Ph_a(n,1) = phi(n,M,r_oc(L),L,Bzeros,L);
                        %                 Ph_a(n,1) = sum(V(:,n).*integral(@(r) phi(n,M,r,L,Bzeros,L),r_ic(L),r_oc(L)));
                        %                 Ph_a(n,1) = integral(@(r) phi(n,M,r,L,Bzeros,L),r_ic(L),r_oc(L));

                        for m = n:N
                            B(n,m) = Bint(n,m,M,Bzeros,e);
                        end
                    end

                    % Symmetry of B
                    for m = 1:N
                        for n = 1:N
                            B(n,m) = B(m,n);
                        end
                    end

                    % Derived pendulum parameters
                    [K,w,Vc,Mmods,Lmods,Hmods,eta0n_xn] = derivedparams(rho,flvol,H,V,D,g,L*a,a,B,hk,Ph_a,intb,selm);

                    % Derived spring parameters
                    [Kmods,HmodsSpring] = pendulum2spring(Mmods, Lmods, g, Hmods);

                    % Save
                    app.Vm      = V;
                    app.Dm      = D;
                    app.Km      = K;
                    app.wm      = w;
                    app.Bzerosm = Bzeros;
                    app.Nm      = N;
                    app.Mm      = M;
                    app.gm      = g;
                    app.hkm     = hk;
                    app.Ph_am   = Ph_a;
                    app.Bm      = B;
                    app.Hmods   = Hmods;
                    app.Kmods   = Kmods;
                    app.HmodsSpring = HmodsSpring;

                    % Dimensionless numbers and regimes
                    We     = rho*Vc^2*a/sigma;
                    Fr     = Vc^2/g/a;
                    Bo     = We/Fr;
                    
                    % Save data
                    M_exp((i-1)*length(FRvec) + j,1:36) = [FRvec(i), gvec(j),...
                       We, Fr, Bo,...
                       w(1:3)',...
                       Mmods(1:selm+1)',Lmods(1:selm+1)',Hmods(1:selm+1)',eta0n_xn(1:selm+1)',...
                       Mmods(1:selm+1)',HmodsSpring(1:selm+1)',Kmods(1:selm+1)'];
                end
            end
            
            % Export
            dlmwrite('export_data.txt',M_exp)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SLOSH_2UIFigure = uifigure('Visible', 'off');
            app.SLOSH_2UIFigure.Name = 'SLOSH_2';
            app.FileMenu = uimenu(app.SLOSH_2UIFigure);
            app.FileMenu.Text = 'File';
            app.SavesessionMenu = uimenu(app.FileMenu);
            app.SavesessionMenu.Text = 'Save session';
            app.LoadsessionMenu = uimenu(app.FileMenu);
            app.LoadsessionMenu.Text = 'Load session';
            app.mainaxes = uiaxes(app.SLOSH_2UIFigure);
            xlabel(app.mainaxes, 'r (m)')
            ylabel(app.mainaxes, 'z (m)')
            app.TabGroup = uitabgroup(app.SLOSH_2UIFigure);
            app.GeometryTab = uitab(app.TabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.GeometryTab.ButtonDownFcn = createCallbackFcn(app, @GeometryTabButtonDown, true);
            app.InnerContourButton = uibutton(app.GeometryTab, 'push');
            app.InnerContourButton.ButtonPushedFcn = createCallbackFcn(app, @inner_contour, true);
            app.InnerContourButton.Text = 'Inner Contour';
            app.OuterContourButton = uibutton(app.GeometryTab, 'push');
            app.OuterContourButton.ButtonPushedFcn = createCallbackFcn(app, @outer_contour, true);
            app.OuterContourButton.Text = 'Outer Contour';
            app.FillRatioLabel = uilabel(app.GeometryTab);
            app.FillRatioLabel.Text = 'Fill Ratio';
            app.LiquidFillRatio = uispinner(app.GeometryTab);
            app.LiquidFillRatio.Limits = [1 99];
            app.LiquidFillRatio.ValueChangedFcn = createCallbackFcn(app, @LiquidFillRationChanged, true);
            app.LiquidFillRatio.Value = 65;
            app.Label = uilabel(app.GeometryTab);
            app.Label.Text = '%';
            app.RFactorLabel = uilabel(app.GeometryTab);
            app.RFactorLabel.Text = 'R Factor ';
            app.RF = uispinner(app.GeometryTab);
            app.RF.Limits = [1 Inf];
            app.RF.ValueChangedFcn = createCallbackFcn(app, @changeRF, true);
            app.RF.Value = 100;
            app.ZFactorLabel = uilabel(app.GeometryTab);
            app.ZFactorLabel.Text = 'Z Factor';
            app.ZF = uispinner(app.GeometryTab);
            app.ZF.Step = 100;
            app.ZF.Limits = [1 Inf];
            app.ZF.ValueChangedFcn = createCallbackFcn(app, @changeZF, true);
            app.ZF.Value = 100;
            app.Label_4 = uilabel(app.GeometryTab);
            app.Label_4.Text = '%';
            app.Label_5 = uilabel(app.GeometryTab);
            app.Label_5.Text = '%';
            app.PhysicsTab = uitab(app.TabGroup);
            app.PhysicsTab.Title = 'Physics';
            app.gEditFieldLabel = uilabel(app.PhysicsTab);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.PhysicsTab, 'numeric');
            app.gEditField.Limits = [0 Inf];
            app.gEditField.ValueDisplayFormat = '%.3f';
            app.gEditField.ValueChangedFcn = createCallbackFcn(app, @changegravity, true);
            app.gEditField.Value = 9.81;
            app.ms2Label = uilabel(app.PhysicsTab);
            app.ms2Label.Text = 'm/s2';
            app.mNmLabel = uilabel(app.PhysicsTab);
            app.mNmLabel.Text = 'mN/m';
            app.EditFieldLabel = uilabel(app.PhysicsTab);
            app.EditFieldLabel.Text = 'σ';
            app.sigmaEditField = uieditfield(app.PhysicsTab, 'numeric');
            app.sigmaEditField.Limits = [0 Inf];
            app.sigmaEditField.ValueDisplayFormat = '%.3f';
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @changetension, true);
            app.sigmaEditField.Value = 72.75;
            app.Kgm3Label = uilabel(app.PhysicsTab);
            app.Kgm3Label.Text = 'Kg/m3';
            app.Label_2 = uilabel(app.PhysicsTab);
            app.Label_2.Text = 'ρ';
            app.rhoEditField = uieditfield(app.PhysicsTab, 'numeric');
            app.rhoEditField.Limits = [0 Inf];
            app.rhoEditField.ValueDisplayFormat = '%.3f';
            app.rhoEditField.ValueChangedFcn = createCallbackFcn(app, @changedensity, true);
            app.rhoEditField.Value = 997;
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.NSpinnerLabel = uilabel(app.SimulationTab);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.SimulationTab);
            app.NSpinner.Limits = [10 Inf];
            app.NSpinner.ValueChangedFcn = createCallbackFcn(app, @changeN, true);
            app.NSpinner.Value = 10;
            app.MSpinnerLabel = uilabel(app.SimulationTab);
            app.MSpinnerLabel.Text = 'M';
            app.MSpinner = uispinner(app.SimulationTab);
            app.MSpinner.Limits = [5 9];
            app.MSpinner.Value = 5;
            app.nSpinnerLabel = uilabel(app.SimulationTab);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.SimulationTab);
            app.nSpinner.Limits = [200 Inf];
            app.nSpinner.Value = 200;
            app.ShowModesLabel = uilabel(app.SimulationTab);
            app.ShowModesLabel.Text = 'Show Modes';
            app.SpringModeSpinner = uispinner(app.SimulationTab);
            app.SpringModeSpinner.Limits = [0 5];
            app.SpringModeSpinner.ValueChangedFcn = createCallbackFcn(app, @changeSpringModeMF, true);
            app.SpringModeSpinner.Value = 3;
            app.ParamAnTab = uitab(app.TabGroup);
            app.ParamAnTab.Title = 'Param. An.';
            app.FR_minSpinnerLabel = uilabel(app.ParamAnTab);
            app.FR_minSpinnerLabel.Text = 'FR_min';
            app.FR_min = uispinner(app.ParamAnTab);
            app.FR_min.Step = 0.1;
            app.FR_min.Limits = [1 99];
            app.FR_min.ValueChangedFcn = createCallbackFcn(app, @FR_minValueChanged, true);
            app.FR_min.Value = 1;
            app.FR_maxSpinnerLabel = uilabel(app.ParamAnTab);
            app.FR_maxSpinnerLabel.Text = 'FR_max';
            app.FR_max = uispinner(app.ParamAnTab);
            app.FR_max.Step = 0.1;
            app.FR_max.Limits = [1 99];
            app.FR_max.ValueChangedFcn = createCallbackFcn(app, @FR_maxValueChanged, true);
            app.FR_max.Value = 99;
            app.g_minSpinnerLabel = uilabel(app.ParamAnTab);
            app.g_minSpinnerLabel.Text = 'g_min';
            app.g_min = uispinner(app.ParamAnTab);
            app.g_min.Step = 0.01;
            app.g_min.Limits = [0.01 Inf];
            app.g_min.ValueDisplayFormat = '%11.5g';
            app.g_min.Value = 0.01;
            app.g_maxSpinnerLabel = uilabel(app.ParamAnTab);
            app.g_maxSpinnerLabel.Text = 'g_max';
            app.g_max = uispinner(app.ParamAnTab);
            app.g_max.Step = 0.01;
            app.g_max.Limits = [0.01 500];
            app.g_max.ValueDisplayFormat = '%11.5g';
            app.g_max.Value = 499.95;
            app.Label_6 = uilabel(app.ParamAnTab);
            app.Label_6.Text = '%';
            app.Label_7 = uilabel(app.ParamAnTab);
            app.Label_7.Text = '%';
            app.ms2Label_2 = uilabel(app.ParamAnTab);
            app.ms2Label_2.Text = 'm/s2';
            app.ms2Label_3 = uilabel(app.ParamAnTab);
            app.ms2Label_3.Text = 'm/s2';
            app.Label_8 = uilabel(app.ParamAnTab);
            app.Label_8.Text = 'Write results for a range of filling ratios (FR) and gravity levels (g)';
            app.NSpinner_2Label = uilabel(app.ParamAnTab);
            app.NSpinner_2Label.Text = 'N';
            app.NFR = uispinner(app.ParamAnTab);
            app.NFR.Limits = [1 500];
            app.NFR.Value = 10;
            app.NSpinner_3Label = uilabel(app.ParamAnTab);
            app.NSpinner_3Label.Text = 'N';
            app.Ngrav = uispinner(app.ParamAnTab);
            app.Ngrav.Limits = [1 500];
            app.Ngrav.ValueDisplayFormat = '%11.5g';
            app.Ngrav.Value = 10;
            app.ExportButton = uibutton(app.ParamAnTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ButtonGroup = uibuttongroup(app.SLOSH_2UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @dispchange, true);
            app.GeometryButton = uitogglebutton(app.ButtonGroup);
            app.GeometryButton.Text = 'Geometry';
            app.GeometryButton.Value = true;
            app.MechanicalButton = uitogglebutton(app.ButtonGroup);
            app.MechanicalButton.Text = 'Mechanical';
            app.ModesButton = uitogglebutton(app.ButtonGroup);
            app.ModesButton.Text = 'Modes';
            app.TabGroup2 = uitabgroup(app.SLOSH_2UIFigure);
            app.BulkOutputTab = uitab(app.TabGroup2);
            app.BulkOutputTab.Title = 'Bulk Output';
            app.eigfntable = uitable(app.BulkOutputTab);
            app.eigfntable.ColumnName = {'1'; '2'; '3'; '4'};
            app.eigfntable.RowName = {};
            app.eigfntable.ColumnEditable = [true false false false];
            app.freqtable = uitable(app.BulkOutputTab);
            app.freqtable.ColumnName = {'1'; '2'; '3'; '4'};
            app.freqtable.RowName = {};
            app.freqtable.ColumnEditable = [true false false false];
            app.EigenvectorsLabel = uilabel(app.BulkOutputTab);
            app.EigenvectorsLabel.Text = 'Eigenvectors';
            app.NaturalFrequenciesLabel = uilabel(app.BulkOutputTab);
            app.NaturalFrequenciesLabel.Text = 'Natural Frequencies';
            app.RegimeLabel = uilabel(app.BulkOutputTab);
            app.RegimeLabel.Text = 'Gravity D. ';
            app.WeEditFieldLabel = uilabel(app.BulkOutputTab);
            app.WeEditFieldLabel.Text = 'We';
            app.WeEditField = uieditfield(app.BulkOutputTab, 'numeric');
            app.WeEditField.Limits = [0 Inf];
            app.WeEditField.ValueDisplayFormat = '%e';
            app.WeEditField.Editable = 'off';
            app.BoEditFieldLabel = uilabel(app.BulkOutputTab);
            app.BoEditFieldLabel.Text = 'Bo';
            app.BoEditField = uieditfield(app.BulkOutputTab, 'numeric');
            app.BoEditField.Limits = [0 Inf];
            app.BoEditField.ValueDisplayFormat = '%e';
            app.BoEditField.Editable = 'off';
            app.FrEditFieldLabel = uilabel(app.BulkOutputTab);
            app.FrEditFieldLabel.Text = 'Fr';
            app.FrEditField = uieditfield(app.BulkOutputTab, 'numeric');
            app.FrEditField.Limits = [0 Inf];
            app.FrEditField.ValueDisplayFormat = '%e';
            app.FrEditField.Editable = 'off';
            app.VcEditFieldLabel = uilabel(app.BulkOutputTab);
            app.VcEditFieldLabel.Text = 'Vc';
            app.VcEditField = uieditfield(app.BulkOutputTab, 'numeric');
            app.VcEditField.Limits = [0 Inf];
            app.VcEditField.ValueDisplayFormat = '%e';
            app.VcEditField.Editable = 'off';
            app.msLabel = uilabel(app.BulkOutputTab);
            app.msLabel.Text = 'm/s';
            app.ModesTab = uitab(app.TabGroup2);
            app.ModesTab.Title = 'Modes';
            app.freqtable_2 = uitable(app.ModesTab);
            app.freqtable_2.ColumnName = {'1'; '2'; '3'; '4'};
            app.freqtable_2.RowName = {};
            app.ModalCoefficientsLabel = uilabel(app.ModesTab);
            app.ModalCoefficientsLabel.Text = 'Modal Coefficients';
            app.ModeLabel = uilabel(app.ModesTab);
            app.ModeLabel.Text = 'Mode:';
            app.ModeSpinner = uispinner(app.ModesTab);
            app.ModeSpinner.Limits = [1 10];
            app.ModeSpinner.ValueChangedFcn = createCallbackFcn(app, @modeplot, true);
            app.ModeSpinner.Enable = 'off';
            app.ModeSpinner.Value = 1;
            app.NaturalFrequencyLabel = uilabel(app.ModesTab);
            app.NaturalFrequencyLabel.Text = 'Natural Frequency:';
            app.NondimFrequencyLabel = uilabel(app.ModesTab);
            app.NondimFrequencyLabel.Text = 'Nondim. Frequency:';
            app.modes_natfreq = uilabel(app.ModesTab);
            app.modes_natfreq.Text = '0';
            app.modes_nondfreq = uilabel(app.ModesTab);
            app.modes_nondfreq.Text = '0';
            app.HzLabel = uilabel(app.ModesTab);
            app.HzLabel.Text = 'Hz';
            app.MultFactorLabel = uilabel(app.ModesTab);
            app.MultFactorLabel.Text = 'Mult. Factor';
            app.modesMF = uispinner(app.ModesTab);
            app.modesMF.Step = 100;
            app.modesMF.Limits = [1 Inf];
            app.modesMF.ValueChangedFcn = createCallbackFcn(app, @changemodeMF, true);
            app.modesMF.Value = 100;
            app.Label_3 = uilabel(app.ModesTab);
            app.Label_3.Text = '%';
            app.PendulumTab = uitab(app.TabGroup2);
            app.PendulumTab.Title = 'Pendulum';
            app.PendulumTable = uitable(app.PendulumTab);
            app.PendulumTable.ColumnName = {'Mi'; 'Li'; 'Hi'; 'CMi'};
            app.PendulumTable.RowName = {};
            app.SpringTab = uitab(app.TabGroup2);
            app.SpringTab.Title = 'Spring';
            app.SpringTable = uitable(app.SpringTab);
            app.SpringTable.ColumnName = {'Mi'; 'Hi'; 'Ki'};
            app.SpringTable.RowName = {};
            app.CALCULATE = uibutton(app.SLOSH_2UIFigure, 'push');
            app.CALCULATE.ButtonPushedFcn = createCallbackFcn(app, @calculate, true);
            app.CALCULATE.Text = 'CALCULATE';
            app.Switch = uiswitch(app.SLOSH_2UIFigure, 'slider');
            app.Switch.Items = {'Unlocked', 'Locked'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @holdcalculate, true);
            app.Switch.Value = 'Unlocked';
            % Show the figure after all components are created
            app.SLOSH_2UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLOSH_v2_ARC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SLOSH_2UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SLOSH_2UIFigure)
        end
    end
end